classdef GazePointAdapterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ProjectFileEditFieldLabel     matlab.ui.control.Label
        ProjectFileEditField          matlab.ui.control.EditField
        AdaptProjectButton            matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        AdaptGazePointProjectLabel    matlab.ui.control.Label
        MetaInformationFileEditFieldLabel  matlab.ui.control.Label
        MetaInformationFileEditField  matlab.ui.control.EditField
        SelectProjectFileButton       matlab.ui.control.Button
        SelectMetaFileButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        image_displacer = ImageDisplacer % Description
    end
    
    methods (Access = private)
        
        function generateAutomaticVideoAndMetaInformationFileNames(app)
            value = app.ProjectFileEditField.Value;
            [path, name, ~] = fileparts(value);
            
            video_file_name_empty = isempty(app.MetaInformationFileEditField_2.Value);
            meta_information_file_name_empty = isempty(app.MetaInformationFileEditField.Value);
            
            if video_file_name_empty
                app.MetaInformationFileEditField_2.Value = [path, filesep, name, '.avi'];
            end
            
            if meta_information_file_name_empty
                app.MetaInformationFileEditField.Value = [path, filesep, name, '_meta_info.txt'];
            end
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: SelectMetaFileButton
        function MetaFileSelector(app, event)
            [file, path] = uigetfile('./*');
            app.MetaInformationFileEditField.Value = [path, file];
        end

        % Button pushed function: AdaptProjectButton
        function AdaptProjectButtonPushed(app, event)
            project_file_name = app.ProjectFileEditField.Value;
            meta_file_name = app.MetaInformationFileEditField.Value;
            
            if isempty(project_file_name)
                warndlg('Image file name cannot be empty');
                return;
            end
            
            if isempty(meta_file_name)
                warndlg('Meta information file name cannot be empty');
                return;
            end
            
            try
                f = msgbox('Work in progress');
                app.image_displacer.adaptProject(project_file_name, meta_file_name);
                close(f);
            catch exc
%                 str = exc.stack
                warndlg(exc.message)
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete();
        end

        % Button pushed function: SelectProjectFileButton
        function ProjectFileSelector(app, event)
            [file, path] = uigetfile('./*');
            if ~isnumeric(path) && ~isnumeric(file)
                app.ProjectFileEditField.Value = [path, file];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ProjectFileEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectFileEditFieldLabel.Text = 'Project File';
            app.ProjectFileEditField = uieditfield(app.UIFigure, 'text');
            app.AdaptProjectButton = uibutton(app.UIFigure, 'push');
            app.AdaptProjectButton.ButtonPushedFcn = createCallbackFcn(app, @AdaptProjectButtonPushed, true);
            app.AdaptProjectButton.Text = 'Adapt Project';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AdaptGazePointProjectLabel = uilabel(app.UIFigure);
            app.AdaptGazePointProjectLabel.Text = 'Adapt GazePoint Project';
            app.MetaInformationFileEditFieldLabel = uilabel(app.UIFigure);
            app.MetaInformationFileEditFieldLabel.Text = 'Meta Information File';
            app.MetaInformationFileEditField = uieditfield(app.UIFigure, 'text');
            app.SelectProjectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectProjectFileButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectFileSelector, true);
            app.SelectProjectFileButton.Text = '';
            app.SelectMetaFileButton = uibutton(app.UIFigure, 'push');
            app.SelectMetaFileButton.ButtonPushedFcn = createCallbackFcn(app, @MetaFileSelector, true);
            app.SelectMetaFileButton.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GazePointAdapterApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end