classdef ImageDisplacerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ImageFileEditFieldLabel        matlab.ui.control.Label
        ImageFileEditField             matlab.ui.control.EditField
        VideoFileEditFieldLabel        matlab.ui.control.Label
        VideoFileEditField             matlab.ui.control.EditField
        WidthofFramepixelsEditFieldLabel  matlab.ui.control.Label
        FrameWidthEditField            matlab.ui.control.NumericEditField
        DisplacementVelocitypixelssecondEditFieldLabel  matlab.ui.control.Label
        DisplacementVelocityEditField  matlab.ui.control.NumericEditField
        GeneratevideoButton            matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        GenerateVideofromImageLabel    matlab.ui.control.Label
        MetaInformationFileEditFieldLabel  matlab.ui.control.Label
        MetaInformationFileEditField   matlab.ui.control.EditField
        SelectImageFileButton          matlab.ui.control.Button
        SelectVideoFileButton          matlab.ui.control.Button
        SelectMetaFileButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        image_displacer = ImageDisplacer % Description
    end
    
    methods (Access = private)
        
        function generateAutomaticVideoAndMetaInformationFileNames(app)
            value = app.ImageFileEditField.Value;
            [path, name, ~] = fileparts(value);
            
            video_file_name_empty = isempty(app.VideoFileEditField.Value);
            meta_information_file_name_empty = isempty(app.MetaInformationFileEditField.Value);
            
            if video_file_name_empty
                app.VideoFileEditField.Value = [path, filesep, name, '.avi'];
            end
            
            if meta_information_file_name_empty
                app.MetaInformationFileEditField.Value = [path, filesep, name, '_meta_info.txt'];
            end
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: SelectImageFileButton
        function ImageFileSelector(app, event)
            [file, path] = uigetfile('./*');
            if ~isnumeric(path) && ~isnumeric(file)
                app.ImageFileEditField.Value = [path, file];
                app.generateAutomaticVideoAndMetaInformationFileNames();
            end
        end

        % Button pushed function: SelectVideoFileButton
        function VideoFileSelector(app, event)
            [file, path] = uigetfile('./*');
            app.VideoFileEditField.Value = [path, file];
        end

        % Button pushed function: SelectMetaFileButton
        function MetaFileSelector(app, event)
            [file, path] = uigetfile('./*');
            app.MetaInformationFileEditField.Value = [path, file];
        end

        % Value changed function: ImageFileEditField
        function ImageFileEditFieldValueChanged(app, event)
            app.generateAutomaticVideoAndMetaInformationFileNames();
        end

        % Button pushed function: GeneratevideoButton
        function GeneratevideoButtonPushed(app, event)
            image_file_name = app.ImageFileEditField.Value;
            output_video_file_name = app.VideoFileEditField.Value;
            meta_file_name = app.MetaInformationFileEditField.Value;
            frame_width_pixels = app.FrameWidthEditField.Value;
            velocity_pixels_per_second = app.DisplacementVelocityEditField.Value;
            
            if isempty(image_file_name)
                warndlg('Image file name cannot be empty');
                return;
            end
            
            if isempty(output_video_file_name)
                warndlg('Video file name cannot be empty');
                return;
            end
            
            if isempty(meta_file_name)
                warndlg('Meta information file name cannot be empty');
                return;
            end
            
            try
                f = msgbox('Work in progress');
                ImageDisplacer.displaceImage(image_file_name, output_video_file_name, meta_file_name, frame_width_pixels, velocity_pixels_per_second);
                close(f);
            catch exc
                warndlg(exc.message)
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ImageFileEditFieldLabel = uilabel(app.UIFigure);
            app.ImageFileEditFieldLabel.Text = 'Image File';
            app.ImageFileEditField = uieditfield(app.UIFigure, 'text');
            app.ImageFileEditField.ValueChangedFcn = createCallbackFcn(app, @ImageFileEditFieldValueChanged, true);
            app.VideoFileEditFieldLabel = uilabel(app.UIFigure);
            app.VideoFileEditFieldLabel.Text = 'Video File';
            app.VideoFileEditField = uieditfield(app.UIFigure, 'text');
            app.WidthofFramepixelsEditFieldLabel = uilabel(app.UIFigure);
            app.WidthofFramepixelsEditFieldLabel.Text = 'Width of Frame (pixels)';
            app.FrameWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.DisplacementVelocitypixelssecondEditFieldLabel = uilabel(app.UIFigure);
            app.DisplacementVelocitypixelssecondEditFieldLabel.Text = 'Displacement Velocity (pixels/second)';
            app.DisplacementVelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.GeneratevideoButton = uibutton(app.UIFigure, 'push');
            app.GeneratevideoButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratevideoButtonPushed, true);
            app.GeneratevideoButton.Text = 'Generate video';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.GenerateVideofromImageLabel = uilabel(app.UIFigure);
            app.GenerateVideofromImageLabel.Text = 'Generate Video from Image';
            app.MetaInformationFileEditFieldLabel = uilabel(app.UIFigure);
            app.MetaInformationFileEditFieldLabel.Text = 'Meta Information File';
            app.MetaInformationFileEditField = uieditfield(app.UIFigure, 'text');
            app.SelectImageFileButton = uibutton(app.UIFigure, 'push');
            app.SelectImageFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImageFileSelector, true);
            app.SelectImageFileButton.Text = '';
            app.SelectVideoFileButton = uibutton(app.UIFigure, 'push');
            app.SelectVideoFileButton.ButtonPushedFcn = createCallbackFcn(app, @VideoFileSelector, true);
            app.SelectVideoFileButton.Text = '';
            app.SelectMetaFileButton = uibutton(app.UIFigure, 'push');
            app.SelectMetaFileButton.ButtonPushedFcn = createCallbackFcn(app, @MetaFileSelector, true);
            app.SelectMetaFileButton.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageDisplacerGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end